classdef GuiMatch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        MovieMenu                      matlab.ui.container.Menu
        SignalMenu                     matlab.ui.container.Menu
        Signal2Menu                    matlab.ui.container.Menu
        Signal3Menu                    matlab.ui.container.Menu
        SinWaveDemoMenu                matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        OnlySignalsMenu                matlab.ui.container.Menu
        CSVMenu                        matlab.ui.container.Menu
        MATMenu                        matlab.ui.container.Menu
        AsAVIMenu                      matlab.ui.container.Menu
        AsGIFMenu                      matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        ResampleMenu                   matlab.ui.container.Menu
        Signal1Menu                    matlab.ui.container.Menu
        Signal2Menu_2                  matlab.ui.container.Menu
        Signal3Menu_2                  matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        ReadmeMenu                     matlab.ui.container.Menu
        Signal3Panel                   matlab.ui.container.Panel
        SelectCommonTimepointButton_3  matlab.ui.control.Button
        TimeVal_3                      matlab.ui.control.Label
        IndexLabel_3                   matlab.ui.control.Label
        TimeLabel_3                    matlab.ui.control.Label
        CommonTimePointVal_3           matlab.ui.control.Label
        CommonTimePointLabel_3         matlab.ui.control.Label
        FsNumValSig_3                  matlab.ui.control.Label
        SamplingRateLabel_3            matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MetaTab                        matlab.ui.container.Tab
        CurrTimeVal                    matlab.ui.control.Label
        Curr_timeLabel                 matlab.ui.control.Label
        DurationValue                  matlab.ui.control.Label
        DurationLabel                  matlab.ui.control.Label
        TotalFramesValue               matlab.ui.control.Label
        TotalFramesLabel               matlab.ui.control.Label
        FsNumValue                     matlab.ui.control.Label
        FsLabel                        matlab.ui.control.Label
        MetadataLabel                  matlab.ui.control.Label
        FrameNum                       matlab.ui.control.Label
        FrameLabel                     matlab.ui.control.Label
        ImageTab                       matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        ResVal                         matlab.ui.control.Label
        CalculateRGBforCurrentFrameButton  matlab.ui.control.Button
        blueval                        matlab.ui.control.Label
        MeanBLabel                     matlab.ui.control.Label
        greenval                       matlab.ui.control.Label
        MeanGLabel                     matlab.ui.control.Label
        redval                         matlab.ui.control.Label
        MeanRLabel                     matlab.ui.control.Label
        ResolutionLabel                matlab.ui.control.Label
        Signal2Panel                   matlab.ui.container.Panel
        SelectCommonTimepointButton_2  matlab.ui.control.Button
        TimeVal_2                      matlab.ui.control.Label
        IndexLabel_2                   matlab.ui.control.Label
        TimeLabel_2                    matlab.ui.control.Label
        CommonTimePointVal_2           matlab.ui.control.Label
        CommonTimePointLabel_2         matlab.ui.control.Label
        FsNumValSig_2                  matlab.ui.control.Label
        SamplingRateLabel_2            matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        TimeSlider                     matlab.ui.control.Slider
        VideoFrameLabel                matlab.ui.control.Label
        FrameControlPanel              matlab.ui.container.Panel
        PlayButton                     matlab.ui.control.Button
        ThirtyUpBtn                    matlab.ui.control.Button
        ThirtyDownBtn                  matlab.ui.control.Button
        TenUpBtn                       matlab.ui.control.Button
        TenDownBtn                     matlab.ui.control.Button
        LockButton                     matlab.ui.control.Button
        ForwardButton                  matlab.ui.control.Button
        BackButton                     matlab.ui.control.Button
        SignalPanel                    matlab.ui.container.Panel
        SelectCommonTimepointButton    matlab.ui.control.Button
        TimeVal                        matlab.ui.control.Label
        IndexLabel                     matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        CommonTimePointVal             matlab.ui.control.Label
        CommonTimePointLabel           matlab.ui.control.Label
        FsNumValSig                    matlab.ui.control.Label
        SamplingRateLabel              matlab.ui.control.Label
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        IMaxes                         matlab.ui.control.UIAxes
        ContextMenu2                   matlab.ui.container.ContextMenu
        RenameSignalMenu               matlab.ui.container.Menu
        RenameDepVarMenu_2             matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        RenameSignalMenu_2             matlab.ui.container.Menu
        RenameDepVarMenu               matlab.ui.container.Menu
        ContextMenu4                   matlab.ui.container.ContextMenu
        RenameSignalMenu_3             matlab.ui.container.Menu
        RenameDepVarMenu_3             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        
        im
        
        i = 1 % Image Indexer
        
        %Selected XY Lock Points
        X
        Y
        
        YLP
        dat %sig1
        dattwo %sig2
        datthree %sig3
        FsSig
        FsSigtwo
        FsSigthree
        
        FsMov
        PlotObj
        PlotObjTwo
        PlotObjThree
        LP_submenu
        
        Res_Submenu % Description
        
        tl = zeros(7,2)
        f
        
        tlineobj
        
        imobj % imshow handle
        
        PlayBool = false; % if true, play, if false, don't play
        
        dotobj1
        dotobj2
        dotobj3
        sig1_exist = false % Did signal 1 get loaded in? init false
        sig2_exist = false % Did signal 2 get loaded in? init false
        sig3_exist = false % Did signal 3 get loaded in? init false
        
        discrep_obj
        discrep_objtwo
        discrep_objthree
    end
    
    properties (Access = public)
        mov % Frame Struct
        % Signal Data ----- x and y components: ONLY RESET THESE IF YOU
        % INTEND TO RESET THE ENTIRE WORKSPACE
        sigx 
        sigy
        
        sigtwox
        sigtwoy
        
        sigthreex
        sigthreey
        
        vreader % Video Reader Object
    end
    
    methods (Access = public)
        
        function updateplot(app,ax,x,y,newfs)
            app.YLP = y;
            
            app.FsSig = newfs;
            
            switch(ax)
                case app.UIAxes
                    app.PlotObj = plot(ax,x,app.YLP,'g');
                    app.FsNumValSig.Text = string(app.FsSig);
                case app.UIAxes_2
                    app.PlotObjTwo = plot(ax,x,app.YLP,'g');
                    app.FsNumValSig.Text = string(app.FsSig);
                case app.UIAxes_3
                    app.PlotObjThree = plot(ax,x,app.YLP,'g');
                    app.FsNumValSig.Text = string(app.FsSig);
                    
            end
            
            
        end
        function update_im_meta(app,frame,curr_time,Fs,tot_frames,dur)
            app.FrameNum.Text = num2str(frame);
            app.CurrTimeVal.Text = num2str(curr_time);
            app.TotalFramesValue.Text = num2str(tot_frames);
            app.FsNumValue.Text = num2str(Fs);
            app.DurationValue.Text = num2str(dur);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sigx, sigy)
           %Init UI Axes Grid
           grid(app.UIAxes,'on');
           grid(app.UIAxes_2,'on');
           grid(app.UIAxes_3,'on');
           
           %Init UIAxes Hold
           hold(app.UIAxes,'on');
           hold(app.UIAxes_2,'on');
           hold(app.UIAxes_3,'on');
           
           %Init tline obj
           
           app.dotobj1 = xline(app.UIAxes,0);
           app.dotobj2 = xline(app.UIAxes_2,0);
           app.dotobj3 = xline(app.UIAxes_3,0);


        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            
            app.i = app.i+1;
            if app.i == app.vreader.NumFrames
                app.i = app.vreader.NumFrames-1;
            end
            cla(app.IMaxes)
            app.imobj = imshow(read(app.vreader,app.i), 'parent', app.IMaxes);
            
            app.CurrTimeVal.Text = string(app.i/app.FsMov);
            
            app.FrameNum.Text = string(app.i);
            
            
            % Timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.i = app.i-1;
            if app.i < 1
                app.i = 1;
            end

            %%%%
            cla(app.IMaxes)
            
            app.imobj = imshow(read(app.vreader,app.i), 'parent', app.IMaxes);
            app.CurrTimeVal.Text = string(app.i/app.FsMov);
            app.FrameNum.Text = string(app.i);
            
            % Timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end
            
        end

        % Callback function
        function SelectCommonTimePointButtonPushed(app, event)
        
            % Unused callback. Delete.
        
        end

        % Callback function
        function ClearButtonPushed(app, event)
            app.dat = [];
            app.dattwo = [];
            cla(app.UIAxes);
            cla(app.UIAxes_2);
        end

        % Button pushed function: TenDownBtn
        function TenDownBtnPushed(app, event)


            app.i = app.i-10;
            cla(app.IMaxes)
            
            if app.i == 1
                app.i = 2;
            elseif app.i < 1
                app.i = 1;
            end
            
            app.imobj = imshow(read(app.vreader,app.i), 'parent', app.IMaxes);
            app.CurrTimeVal.Text = string(app.i/app.FsMov);
            app.FrameNum.Text = string(app.i);
            
            
            % Timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end
        end

        % Button pushed function: ThirtyDownBtn
        function ThirtyDownBtnButtonPushed(app, event)
            app.i = app.i-30;
            cla(app.IMaxes)
            
            if app.i == 1
                app.i = 2;
            elseif app.i < 1
                app.i = 1;
            end
            
            app.imobj = imshow(read(app.vreader,app.i), 'parent', app.IMaxes);     
            app.CurrTimeVal.Text = string(app.i/app.FsMov);
            app.FrameNum.Text = string(app.i);
            
            % Timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end
            
        end

        % Button pushed function: TenUpBtn
        function TenUpBtnButtonPushed(app, event)
            app.i = app.i+10;
            
            
            if app.i == app.vreader.NumFrames
                app.i = app.vreader.NumFrames-1;
                
            elseif app.i > app.vreader.NumFrames
                app.i = app.vreader.NumFrames-1;
            end
            
            cla(app.IMaxes)
            

            app.imobj = imshow(read(app.vreader,app.i), 'parent', app.IMaxes);
            app.CurrTimeVal.Text = string(app.i/app.FsMov);
            app.FrameNum.Text = string(app.i);
            
            % Timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end
            
        end

        % Button pushed function: ThirtyUpBtn
        function ThirtyUpBtnButtonPushed(app, event)
             app.i = app.i+30;
            
            
            if app.i == app.vreader.NumFrames
                app.i = app.vreader.NumFrames-1;
                
            elseif app.i > app.vreader.NumFrames
                app.i = app.vreader.NumFrames-1;
            end
            
            cla(app.IMaxes)
           
            
            app.imobj = imshow(read(app.vreader,app.i), 'parent', app.IMaxes);
            app.CurrTimeVal.Text = string(app.i/app.FsMov);
            app.FrameNum.Text = string(app.i);
            
            % Timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end
            
        end

        % Menu selected function: MovieMenu
        function MovieMenuSelected(app, event)
            
            [file, path] = uigetfile("*");
            filepath = [path file];
            
            d=uiprogressdlg(app.UIFigure,'Title','Loading Movie...','Message','Loading Movie');
            
            % v = VideoReader(filepath);
             app.f = dir(filepath);
             app.vreader = VideoReader(filepath);
             init_frame = read(app.vreader,1);
             
             app.FsMov = app.vreader.FrameRate;
             
             app.imobj = imshow(init_frame,'parent',app.IMaxes);


             app.TimeSlider.Limits = [1 app.vreader.NumFrames];
             close(d);
     
             %index uint8 arrays like this: v(:,:,:,i)
        end

        % Menu selected function: SignalMenu
        function SignalMenuSelected(app, event)
            [file, path] = uigetfile("*.csv");
            if file == 0
                dum = 1;
                
            elseif file ~= 0 
                d=uiprogressdlg(app.UIFigure,'Title','Loading Signal...','Message','Loading Signal');
                
                app.dat = readtable(strcat(path,file),'PreserveVariableNames',false);
                app.dat.Properties.VariableNames = {'Var1','Var2'};
                app.sigx = app.dat.Var1;
                
                try
                    app.sigy = app.dat.Var2;
                catch ME
                    if all(ME.identifier == 'MATLAB:table:UnrecognizedVarName')
                        fs = inputdlg("No X component in file. Enter sampling rate to generate one: ");
                        fs = double(string(cell2mat(fs)));
                        

                        app.sigy = app.sigx;
                        app.sigx = 0:1/fs:numel(app.sigy)/fs;

                        if numel(app.sigx) > numel(app.sigy)
                            df = numel(app.sigx) - numel(app.sigy);
                            app.sigx = app.sigx(1:numel(app.sigx) - df); 
                        end

                        app.dat = [app.sigx' app.sigy];
                    end
                end
                d.Value = .5;
                [Fsmeasured, Fsround] = FindFs(app.sigx);
                app.FsSig = Fsmeasured;
                
                close(d)
                if not(Fsround == int64(Fsmeasured)) == 1 
                    uialert(app.UIFigure,strcat("Uneven sampling rates can reduce synchronization accuracy. Consider resampling. Measured Fs was ",string(Fsmeasured),'Hz'),'Variable Fs')    
                end
            
                app.FsNumValSig.Text = string(Fsround);
                app.PlotObj = plot(app.UIAxes, app.sigx, app.sigy);
                app.sig1_exist = true;
            end


        end

        % Callback function
        function LowPassMenuSelected(app, event)
            app.LP_submenu = LP_Submen(app,app.dat,app.FsSig);
        end

        % Menu selected function: ResampleMenu
        function ResampleMenuSelected(app, event)
           
            
        end

        % Button pushed function: LockButton
        function LockButtonPushed(app, event)
            %Signal One%
            app.ResampleMenu.Enable = 0;
            lockframenum = str2double(app.FrameNum.Text);
            MovLockTime = str2double(app.CurrTimeVal.Text);
            
            SigLockIdx = str2double(app.CommonTimePointVal.Text);
            SigLockTime = app.sigx(SigLockIdx);
            
            if MovLockTime > SigLockTime
                dt = MovLockTime - SigLockTime;
                
                [dstream,zstream] = gen(round(app.FsSig),dt,app.dat);
                newdat = dstream;
                
                app.sigx = newdat(:,1);
                app.sigy = newdat(:,2);
                
                
                delete(app.PlotObj);
                updateplot(app,app.UIAxes,app.sigx,app.sigy,app.FsSig)
                
                
                app.discrep_obj = plot(app.UIAxes,zstream(:,1),zstream(:,2),'m');
            %    legend(app.UIAxes,"Signal","Null Values");
                
                
            elseif MovLockTime < SigLockTime
                dt = SigLockTime - MovLockTime;
                [dstream,zstream] = forgen(app.FsSig,dt,app.dat);
                newdat = dstream;
                app.sigx = newdat(:,1);
                app.sigy = newdat(:,2);
                
               
                delete(app.PlotObj);
                updateplot(app,app.UIAxes,app.sigx,app.sigy,app.FsSig);
                
                
                app.discrep_obj = plot(app.UIAxes,zstream(:,1),zstream(:,2),'m');
           %     legend(app.UIAxes,"Signal","Null Values");
               
                
            end
            
            %Signal Two%


            if app.sig2_exist == true
                SigTwoLockIdx = str2double(app.CommonTimePointVal_2.Text);
                SigTwoLockTime = app.sigtwox(SigTwoLockIdx);
                if MovLockTime > SigTwoLockTime
                    dt = MovLockTime - SigTwoLockTime;
                    [dstream,zstream] = gen(round(app.FsSigtwo),dt,app.dattwo);
                    newdat = dstream;
                
                    app.sigtwox = newdat(:,1);
                    app.sigtwoy = newdat(:,2);
                
                    delete(app.PlotObjTwo)
                    updateplot(app,app.UIAxes_2,app.sigtwox,app.sigtwoy,app.FsSigtwo);
                
                    
                    app.discrep_objtwo = plot(app.UIAxes_2,zstream(:,1),zstream(:,2),'m');
              %      legend(app.UIAxes_2,'Signal',"Null Values");
                    
                
                elseif MovLockTime < SigTwoLockTime
                    dt = SigTwoLockTime - MovLockTime;
                    [dstream,zstream] = forgen(app.FsSigtwo,dt,app.dattwo);
                    newdat = dstream;
                
                    app.sigtwox = newdat(:,1);
                    app.sigtwoy = newdat(:,2);
                
                    delete(app.PlotObjTwo);
                    updateplot(app,app.UIAxes_2,app.sigtwox,app.sigtwoy,app.FsSigtwo);
                
                    
                    app.discrep_objtwo = plot(app.UIAxes_2,zstream(:,1),zstream(:,2),'m');
           %         legend(app.UIAxes_2,"Signal","Null Values");
                    
                else
                    dum=1;
                
                
                end


            elseif app.sig2_exist == false
                dum = 1;
            end
            
            
            % Signal 3 
            if app.sig3_exist == true
                SigThreeLockIdx = str2double(app.CommonTimePointVal_3.Text);
                SigThreeLockTime = app.sigthreex(SigThreeLockIdx);
                if MovLockTime > SigThreeLockTime
                    dt = MovLockTime - SigThreeLockTime;
                    [dstream,zstream] = gen(round(app.FsSigthree),dt,app.datthree);
                    newdat = dstream;
                
                    app.sigthreex = newdat(:,1);
                    app.sigthreey = newdat(:,2);
                
                    delete(app.PlotObjThree)
                    updateplot(app,app.UIAxes_3,app.sigthreex,app.sigthreey,app.FsSigthree);
                
                    
                    app.discrep_objthree = plot(app.UIAxes_3,zstream(:,1),zstream(:,2),'m');
              %      legend(app.UIAxes_3,'Signal',"Null Values");
                    
                
                elseif MovLockTime < SigThreeLockTime
                    dt = SigThreeLockTime - MovLockTime;
                    [dstream,zstream] = forgen(app.FsSigthree,dt,app.datthree);
                    newdat = dstream;
                
                    app.sigthreex = newdat(:,1);
                    app.sigthreey = newdat(:,2);
                
                    delete(app.PlotObjThree);
                    updateplot(app,app.UIAxes_3,app.sigthreex,app.sigthreey,app.FsSigthree);
                
                    
                    app.discrep_objthree = plot(app.UIAxes_3,zstream(:,1),zstream(:,2),'m');
           %         legend(app.UIAxes_2,"Signal","Null Values");
                    
                else
                    dum = 1;
                end
            end


        end

        % Callback function
        function SelectBeginningButtonPushed(app, event)
            app.CommonTimePointVal.Text = string(1);
            app.TimeVal.Text = string(app.dat.Var1(1)) ;
        end

        % Menu selected function: Signal2Menu
        function Signal2MenuSelected(app, event)
            [file, path] = uigetfile("*.csv");
            if file == 0
                dum = 1;
                
            elseif file ~= 0 
                app.sig2_exist = true;
                
                d=uiprogressdlg(app.UIFigure,'Title','Loading Signal...','Message','Loading Signal');
                
                app.dattwo = readtable(strcat(path,file),'PreserveVariableNames',false);
                app.dattwo = app.dattwo(:,1);
                app.sigtwox = app.dattwo.Var1;
                % app.sigtwoy = app.dattwo.Var2;
                
                try
                    app.sigtwoy = app.dattwo.Var2;
                catch ME
                    if all(ME.identifier == 'MATLAB:table:UnrecognizedVarName')
                        fs = inputdlg("No X component in file. Enter sampling rate to generate one: ");
                        fs = double(string(cell2mat(fs)));
                        

                        app.sigtwoy = app.sigtwox;
                        app.sigtwox = 0:1/fs:numel(app.sigtwoy)/fs;

                        if numel(app.sigtwox) > numel(app.sigtwoy)
                            df = numel(app.sigtwox) - numel(app.sigtwoy);
                            app.sigtwox = app.sigtwox(1:numel(app.sigtwox) - df); 
                        end
                        app.dattwo = [app.sigtwox' app.sigtwoy];
                    end
                end


                d.Value = .5;
                [Fsmeasured, Fsround] = FindFs(app.sigtwox);
                app.FsSigtwo = Fsmeasured;
                
                close(d)
                if not(Fsround == int64(Fsmeasured)) == 1 
                    uialert(app.UIFigure,strcat("Uneven sampling rates can reduce synchronization accuracy. Consider resampling. Measured Fs was ",string(Fsmeasured),'Hz'),'Variable Fs')    
                end
            
                app.FsNumValSig_2.Text = string(Fsround);
                app.PlotObjTwo = plot(app.UIAxes_2, app.sigtwox, app.sigtwoy);
            end
        end

        % Button pushed function: CalculateRGBforCurrentFrameButton
        function CalculateRGBforCurrentFrameButtonPushed(app, event)
            app.redval.Text = string(mean2(app.mov(app.i).cdata(:,:,1)));
            app.greenval.Text = string(mean2(app.mov(app.i).cdata(:,:,2)));
            app.blueval.Text = string(mean2(app.mov(app.i).cdata(:,:,3)));
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.PlayBool = not(app.PlayBool);
            while app.PlayBool == true
                app.i = round(app.TimeSlider.Value);
                
                while app.i < app.vreader.NumFrames
                    
                    
                    pause(1/app.FsMov)
                    %imshow(app.mov(app.i).cdata,'parent',app.IMaxes);
                    
                    app.imobj.CData = read(app.vreader,app.i);
                    app.i = app.i + 1;


                    if app.i > app.vreader.NumFrames
                        app.PlayBool = false;
                        break
                        
                    end
                    
                    if app.i == app.vreader.NumFrames
                        app.PlayBool = false;
                        break
                    end
                   
                    %Update Slider Value
                    app.TimeSlider.Value = app.i;
                    
                    %Update timeline while playing
                    
                    app.dotobj1.Value = app.i/app.FsMov;
                    
                    switch(app.sig2_exist)
                        case true
                            app.dotobj2.Value = app.i/app.FsMov;
                        case false
                            break
                    end
                    
                   if app.PlayBool == false
                        break
                   end 
                    
                end
                
                % Turn all of the stuff where you update the movie
                % metadata into a function. That way, you can call that
                % function here without individually changing property
                % values!
                
                if app.i == app.vreader.NumFrames || app.i > app.vreader.NumFrames
                    app.i = app.vreader.NumFrames;
                    
                end
            end
                
          
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.i = round(changingValue);
            app.imobj.CData = read(app.vreader,app.i);
            
            update_im_meta(app,app.i,app.i/app.FsMov,app.FsMov,app.vreader.NumFrames,app.vreader.NumFrames/app.FsMov);

            %update timeline
            xval = str2num(app.CurrTimeVal.Text);
            app.dotobj1.Value = xval;
            
            % Timeline 2/3
            
            %Check if the second signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig2_exist)
                case true
                    app.dotobj2.Value = xval;
            end
            %Check if the third signal exists, if it does, go ahead and
            %update the X timeline 
            switch(app.sig3_exist)
                case true
                    app.dotobj3.Value = xval;
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
         
        end

        % Button pushed function: SelectCommonTimepointButton
        function SelectCommonTimepointButtonPushed(app, event)
            [x,~] = selectDatapoints(app.UIAxes);
            x = x(ceil(numel(x)/2));
            idx_slct = find(app.sigx == x);
            idx_slct = idx_slct(1);
            app.CommonTimePointVal.Text = num2str(idx_slct);
            app.TimeVal.Text = num2str(x);
        end

        % Button pushed function: SelectCommonTimepointButton_2
        function SelectCommonTimepointButton_2Pushed(app, event)
            [x,~] = selectDatapoints(app.UIAxes_2);
            x = x(ceil(numel(x)/2));
            idx_slct = find(app.sigtwox == x);
            idx_slct = idx_slct(1);
            app.CommonTimePointVal_2.Text = num2str(idx_slct);
            app.TimeVal_2.Text = num2str(x);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            app.UIFigure.Visible = false;
        end

        % Menu selected function: AsAVIMenu
        function AsAVIMenuSelected(app, event)
            % Prompt User
            [file, path] = uiputfile('*.avi');
            
            
            exp_fig = figure;
            %exp_fig('units','pixels','position',[0 0 1920 1080]);
            
            
            if app.sig1_exist == true && app.sig2_exist == false && app.sig3_exist == false == true %just signal 1?
                exp_fig.Visible = 'off';
                v_ax = subplot(2,1,1);
                sig_ax = subplot(2,1,2);
                sz = [app.vreader.Width app.vreader.Height];
                output = struct('cdata',zeros(sz(1),sz(2),3,'uint8'),'colormap',[]);
                
                %init sig1
                hold(sig_ax,'on')
                discrp1 = plot(sig_ax, app.discrep_obj.XData, app.discrep_obj.YData,'m');
                sig1 = plot(sig_ax,app.PlotObj.XData, app.PlotObj.YData, 'b');
                ylim([min(app.sigy) max(app.sigy)]);
                
                
                %init video
                subplot(2,1,1);
                v_ax_ob = imshow(read(app.vreader,1));
                
                
                d=uiprogressdlg(app.UIFigure,'Title','Exporting Video...','Message','Your data is being exported...');

                % open video object
                v = VideoWriter(strcat(path,file),'Motion JPEG AVI');
                
                % Set to correct framerate
                v.FrameRate = app.FsMov;
                open(v);
                
                % start looping
                iter = 1;
                while iter < app.vreader.NumFrames
                    v_ax_ob.CData = read(app.vreader,iter);
                    frm = getframe(exp_fig);
                    writeVideo(v,frm);
                    sig_ax.XLim = [iter/app.FsMov-5 iter/app.FsMov];                    
                    d.Value = 1/iter;
                    
                    iter = iter + 1;
                    disp(iter);
                    %cla
                end
               
                close(v);
                close(d);


            elseif app.sig2_exist == true & app.sig3_exist == false % signal 1 and 2?
                % exp_fig.Visible = 'off';
                v_ax = subplot(3,1,1);
                sig_ax = subplot(3,1,2);
                sig2_ax = subplot(3,1,3);
                sz = [app.vreader.Width app.vreader.Height];
                output = struct('cdata',zeros(sz(1),sz(2),3,'uint8'),'colormap',[]);


                subplot(3,1,2);
                hold(sig_ax,'on')
                discrp1 = plot(subplot(3,1,2),app.discrep_obj.XData, app.discrep_obj.YData,'m');
                sig1 = plot(subplot(3,1,2),app.PlotObj.XData,app.PlotObj.YData,'b');
                ylim([min(app.sigy) max(app.sigy)])
                
                subplot(3,1,3);
                hold(sig2_ax,'on')
                discrp2 = plot(subplot(3,1,3),app.discrep_objtwo.XData, app.discrep_objtwo.YData,'m');
                sig2 = plot(app.PlotObjTwo.XData,app.PlotObjTwo.YData,'b');
                ylim([min(app.sigtwoy) max(app.sigtwoy)])
                
                subplot(3,1,1)
                v_ax_ob = imshow(read(app.vreader,1));
                
                
                d=uiprogressdlg(app.UIFigure,'Title','Exporting Video...','Message','Your data is being exported...');
                
                
                % Open video object
                v = VideoWriter(strcat(path,file),'Motion JPEG AVI');
                
                % Set Framerate to correct 
                v.FrameRate = app.FsMov;
                %v.Quality = 100;
                
                open(v);
                
                
                % Start Looping
                iter = 1;
                while iter < app.vreader.NumFrames
                    v_ax_ob.CData = read(app.vreader,iter);
                    frm = getframe(exp_fig);
                    writeVideo(v,frm);
                    sig_ax.XLim = [iter/app.FsMov-5 iter/app.FsMov];
                    sig2_ax.XLim = [iter/app.FsMov-5 iter/app.FsMov];
                    
                    d.Value = 1/iter;
                    
                    iter = iter + 1;
                    disp(iter);
                    %cla
                end
               
                close(v);
                close(d);
                
            elseif app.sig2_exist && app.sig3_exist == true % signals 1 2 and 3?
                exp_fig.Visible = 'off';
                v_ax = subplot(4,1,1);
                sig_ax = subplot(4,1,2);
                sig2_ax = subplot(4,1,3);
                sig3_ax = subplot(4,1,4);
                sz = [app.vreader.Width app.vreader.Height];
                output = struct('cdata',zeros(sz(1),sz(2),3,'uint8'),'colormap',[]);
                
                %init sig 1
                hold(sig_ax,'on')
                discrp1 = plot(sig_ax,app.discrep_obj.XData, app.discrep_obj.YData,'m');
                sig1 = plot(sig_ax,app.PlotObj.XData,app.PlotObj.YData,'b');
                ylim([min(app.sigy) max(app.sigy)])
                
                %init sig 2
                hold(sig2_ax,'on')
                discrp2 = plot(sig2_ax,app.discrep_objtwo.XData, app.discrep_objtwo.YData,'m');
                sig2 = plot(sig2_ax,app.PlotObjTwo.XData,app.PlotObjTwo.YData,'b');
                ylim([min(app.sigtwoy) max(app.sigtwoy)])
                
                %init sig3
                hold(sig3_ax,'on')
                discrp3 = plot(sig3_ax,app.discrep_objthree.XData, app.discrep_objthree.YData,'m');
                sig3 = plot(sig3_ax,app.PlotObjThree.XData, app.PlotObjThree.YData,'b');
                ylim([min(app.sigthreey) max(app.sigthreey)])
                
                %init video
                subplot(4,1,1);
                v_ax_ob = imshow(read(app.vreader,1));
                
                d=uiprogressdlg(app.UIFigure,'Title','Exporting Video...','Message','Your data is being exported...');
                
                % Open video object
                v = VideoWriter(strcat(path,file),'Motion JPEG AVI');
                
                % Set Framerate to correct 
                v.FrameRate = app.FsMov;
                %v.Quality = 100;
                
                open(v);
                
                
                % Start Looping
                iter = 1;
                while iter < app.vreader.NumFrames
                    v_ax_ob.CData = read(app.vreader,iter);
                    frm = getframe(exp_fig);
                    writeVideo(v,frm);
                    sig_ax.XLim = [iter/app.FsMov-5 iter/app.FsMov];
                    sig2_ax.XLim = [iter/app.FsMov-5 iter/app.FsMov];
                    sig3_ax.XLim = [iter/app.FsMov-5 iter/app.FsMov];
                    
                    d.Value = 1/iter;
                    
                    iter = iter + 1;
                    disp(iter);
                    %cla
                end
               
                close(v);
                close(d);


            end


        end

        % Menu selected function: ReadmeMenu
        function ReadmeMenuSelected(app, event)
            web('https://github.com/manashkalyon/sigsync/blob/master/README.md');
        end

        % Menu selected function: Signal1Menu
        function Signal1MenuSelected(app, event)
            res_fs = inputdlg('Enter new sampling rate','Resample Signal 1', [1 25]);
            res_fs = str2double(res_fs{1});
            [app.sigx, app.sigy] = resamp(app.sigx,app.sigy,res_fs);
            delete(app.PlotObj);
            updateplot(app,app.UIAxes,app.sigx,app.sigy,res_fs);
        end

        % Menu selected function: Signal2Menu_2
        function Signal2Menu_2Selected(app, event)
            res_fs = inputdlg('Enter new sampling rate','Resample Signal 2', [1 25]);
            res_fs = str2double(res_fs{1});
            [app.sigtwox, app.sigtwoy] = resamp(app.sigtwox,app.sigtwoy,res_fs);
            delete(app.PlotObj);
            updateplot(app,app.UIAxes_2,app.sigtwox,app.sigtwoy,res_fs);
        end

        % Menu selected function: RenameSignalMenu
        function RenameSignalMenuSelected(app, event)
            newname = inputdlg('Enter new name for Signal 1','Rename Signal 1', [1 25],{'Signal 1'});
            app.UIAxes.Title.String = newname{1};
        end

        % Menu selected function: RenameSignalMenu_2
        function RenameSignalMenu_2Selected(app, event)
            newname = inputdlg('Enter new name for Signal 2','Rename Signal 2', [1 25],{'Signal 2'});
            app.UIAxes_2.Title.String = newname{1};
        end

        % Menu selected function: RenameDepVarMenu_2
        function RenameDepVarMenu_2Selected(app, event)
            newname = inputdlg('Enter new label for Y axis','Rename Y Axis', [1 25],{'Y'});
            app.UIAxes.YLabel.String = newname{1};
        end

        % Menu selected function: RenameDepVarMenu
        function RenameDepVarMenuSelected(app, event)
            newname = inputdlg('Enter new label for Y axis','Rename Y Axis', [1 25],{'Y'});
            app.UIAxes_2.YLabel.String = newname{1};
        end

        % Menu selected function: AsGIFMenu
        function AsGIFMenuSelected(app, event)
            
        end

        % Menu selected function: Signal3Menu
        function Signal3MenuSelected(app, event)
            [file, path] = uigetfile("*.csv");
            if file == 0
                dum = 1;
                
            elseif file ~= 0 
                app.sig3_exist = true;
                
                d=uiprogressdlg(app.UIFigure,'Title','Loading Signal...','Message','Loading Signal');
                
                app.datthree = readtable(strcat(path,file),'PreserveVariableNames',false);
                app.sigthreex = app.datthree.Var1;
                app.sigthreey = app.datthree.Var2;
                
                d.Value = .5;
                [Fsmeasured, Fsround] = FindFs(app.sigthreex);
                app.FsSigthree = Fsmeasured;
                
                close(d)
                if not(Fsround == int64(Fsmeasured)) == 1 
                    uialert(app.UIFigure,strcat("Uneven sampling rates can reduce synchronization accuracy. Consider resampling. Measured Fs was ",string(Fsmeasured),'Hz'),'Variable Fs')    
                end
            
                app.FsNumValSig_3.Text = string(Fsround);
                app.PlotObjThree = plot(app.UIAxes_3, app.sigthreex, app.sigthreey);
                
            end
        end

        % Button pushed function: SelectCommonTimepointButton_3
        function SelectCommonTimepointButton_3Pushed(app, event)
            [x,~] = selectDatapoints(app.UIAxes_3);
            x = x(ceil(numel(x)/2));
            idx_slct = find(app.sigthreex == x);
            idx_slct = idx_slct(1);
            app.CommonTimePointVal_3.Text = num2str(idx_slct);
            app.TimeVal_3.Text = num2str(x);
        end

        % Menu selected function: Signal3Menu_2
        function Signal3Menu_2Selected(app, event)
            res_fs = inputdlg('Enter new sampling rate','Resample Signal 3', [1 25]);
            res_fs = str2double(res_fs{1});
            [app.sigthreex, app.sigthreey] = resamp(app.sigthreex,app.sigthreey,res_fs);
            delete(app.PlotObjThree);
            updateplot(app,app.UIAxes_3,app.sigthreex,app.sigthreey,res_fs);
        end

        % Menu selected function: CSVMenu
        function CSVMenuSelected(app, event)
            [file, path] = uiputfile('*.csv');
            
            if app.sig2_exist && app.sig3_exist == true
                %concat signals
                
                    %sig1
                    dscrp(:,1) = app.discrep_obj.XData;
                    dscrp(:,2) = app.discrep_obj.YData;
                    sdat(:,1) = app.sigx;
                    sdat(:,2) = app.sigy;
                    signalone = vertcat(dscrp,sdat);
                    
                    
                    %sig2
                    dscrp2(:,1) = app.discrep_objtwo.XData;
                    dscrp2(:,2) = app.discrep_objtwo.YData;
                    dat2(:,1) = app.sigtwox;
                    dat2(:,2) = app.sigtwoy;
                    signaltwo = vertcat(dscrp2,dat2);
                    
                    
                    %sig3
                    dscrp3(:,1) = app.discrep_obj.XData;
                    dscrp3(:,2) = app.discrep_obj.YData;
                    dat3(:,1) = app.sigx;
                    dat3(:,2) = appp.sigy;
                    signalthree=vertcat(dscrp,dat3);


            end
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            
        end

        % Menu selected function: RenameSignalMenu_3
        function RenameSignalMenu_3Selected(app, event)
            newname = inputdlg('Enter new name for Signal 3','Rename Signal 3', [1 25],{'Signal 3'});
            app.UIAxes_3.Title.String = newname{1};
        end

        % Menu selected function: RenameDepVarMenu_3
        function RenameDepVarMenu_3Selected(app, event)
            newname = inputdlg('Enter new label for Y axis','Rename Y Axis', [1 25],{'Y'});
            app.UIAxes_3.YLabel.String = newname{1};
        end

        % Menu selected function: SinWaveDemoMenu
        function SinWaveDemoMenuSelected(app, event)
            ef = frame2plot(app.UIAxes,'/Users/kalyon/MATLAB-Drive/ANotB Data/lol.csv');
        end

        % Menu selected function: MATMenu
        function MATMenuSelected(app, event)
            
            if app.sig2_exist && app.sig3_exist == true
                %concat signals
                
                    %sig
                    sigone(:,1) = app.sigx;
                    sigone(:,2) = app.sigy;
                    
                    %sig2   
                    sig2(:,1) = app.sigtwox;
                    sig2(:,2) = app.sigtwoy;

                    %sig3
                    sig3(:,1) = app.sigx;
                    sig3(:,2) = app.sigy;
                  
                    
                    %save signals
                    save('SynchSignals.mat','sigone','sig2','sig3');
            elseif app.sig2_exist && not(app.sig3_exist) == true
                    %sig
                    sigone(:,1) = app.sigx;
                    sigone(:,2) = app.sigy;
                    
                    %sig2   
                    sig2(:,1) = app.sigtwox;
                    sig2(:,2) = app.sigtwoy;
                    %save signals
                    path = uigetdir;
                    cd(path);
                    save('SynchSignals.mat','sigone','sig2');
            elseif app.sig2_exist == false
                sigone(:,1) = app.sigx;
                sigone(:,2) = app.sigy;
                save('SynchSignals.mat','sigone','sig2');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.sig3_exist && app.sig2_exist == true % sig 1 2 and 3
                app.sigx = table2array(app.dat(:,1));
                app.sigy = table2array(app.dat(:,2));
                
                app.sigtwox = table2array(app.dattwo(:,1));
                app.sigtwoy = table2array(app.dattwo(:,2));
                
                app.sigthreex = table2array(app.datthree(:,1));
                app.sigthreey = table2array(app.datthree(:,2));
                
                cla(app.UIAxes)
                cla(app.UIAxes_2)
                cla(app.UIAxes_3)
                
                app.dotobj1 = xline(app.UIAxes,0);
                app.dotobj2 = xline(app.UIAxes_2,0);
                app.dotobj3 = xline(app.UIAxes_3,0);
                
                updateplot(app,app.UIAxes,app.sigx,app.sigy,FindFs(app.sigx))
                updateplot(app,app.UIAxes_2,app.sigtwox,app.sigtwoy,FindFs(app.sigtwox))
                updateplot(app,app.UIAxes_3,app.sigthreex,app.sigthreey,FindFs(app.sigthreex))


            elseif app.sig2_exist && not(app.sig3_exist) == true % just sig2 and sig1?
                app.sigx = table2array(app.dat(:,1));
                app.sigy = table2array(app.dat(:,2));
                
                app.sigtwox = table2array(app.dattwo(:,1));
                app.sigtwoy = table2array(app.dattwo(:,2));
                
                cla(app.UIAxes)
                cla(app.UIAxes_2)
                
                app.dotobj1 = xline(app.UIAxes,0);
                app.dotobj2 = xline(app.UIAxes_2,0);
                
                updateplot(app,app.UIAxes,app.sigx,app.sigy,FindFs(app.sigx))
                updateplot(app,app.UIAxes_2,app.sigtwox,app.sigtwoy,FindFs(app.sigtwox))
                
            elseif app.sig2_exist && app.sig3_exist == false % just sig 1
                app.sigx = table2array(app.dat(:,1));
                app.sigy = table2array(app.dat(:,2));
                
                cla(app.UIAxes)
                
                app.dotobj1 = xline(app.UIAxes,0);
                updateplot(app,app.UIAxes,app.sigx,app.sigy,FindFs(app.sigx))

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File...';
            app.MovieMenu = uimenu(app.FileMenu);
            app.MovieMenu.MenuSelectedFcn = createCallbackFcn(app, @MovieMenuSelected, true);
            app.MovieMenu.Text = 'Movie';
            app.SignalMenu = uimenu(app.FileMenu);
            app.SignalMenu.MenuSelectedFcn = createCallbackFcn(app, @SignalMenuSelected, true);
            app.SignalMenu.Text = 'Signal';
            app.Signal2Menu = uimenu(app.FileMenu);
            app.Signal2Menu.MenuSelectedFcn = createCallbackFcn(app, @Signal2MenuSelected, true);
            app.Signal2Menu.Text = 'Signal 2';
            app.Signal3Menu = uimenu(app.FileMenu);
            app.Signal3Menu.MenuSelectedFcn = createCallbackFcn(app, @Signal3MenuSelected, true);
            app.Signal3Menu.Text = 'Signal 3';
            app.SinWaveDemoMenu = uimenu(app.FileMenu);
            app.SinWaveDemoMenu.MenuSelectedFcn = createCallbackFcn(app, @SinWaveDemoMenuSelected, true);
            app.SinWaveDemoMenu.Text = 'Sin Wave Demo';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export...';
            app.OnlySignalsMenu = uimenu(app.ExportMenu);
            app.OnlySignalsMenu.Text = 'Only Signals';
            app.CSVMenu = uimenu(app.OnlySignalsMenu);
            app.CSVMenu.MenuSelectedFcn = createCallbackFcn(app, @CSVMenuSelected, true);
            app.CSVMenu.Text = 'CSV';
            app.MATMenu = uimenu(app.OnlySignalsMenu);
            app.MATMenu.MenuSelectedFcn = createCallbackFcn(app, @MATMenuSelected, true);
            app.MATMenu.Text = 'MAT';
            app.AsAVIMenu = uimenu(app.ExportMenu);
            app.AsAVIMenu.MenuSelectedFcn = createCallbackFcn(app, @AsAVIMenuSelected, true);
            app.AsAVIMenu.Text = 'As .AVI';
            app.AsGIFMenu = uimenu(app.ExportMenu);
            app.AsGIFMenu.MenuSelectedFcn = createCallbackFcn(app, @AsGIFMenuSelected, true);
            app.AsGIFMenu.Enable = 'off';
            app.AsGIFMenu.Text = 'As .GIF';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools...';
            app.ResampleMenu = uimenu(app.ToolsMenu);
            app.ResampleMenu.MenuSelectedFcn = createCallbackFcn(app, @ResampleMenuSelected, true);
            app.ResampleMenu.Text = 'Resample';
            app.Signal1Menu = uimenu(app.ResampleMenu);
            app.Signal1Menu.MenuSelectedFcn = createCallbackFcn(app, @Signal1MenuSelected, true);
            app.Signal1Menu.Text = 'Signal 1';
            app.Signal2Menu_2 = uimenu(app.ResampleMenu);
            app.Signal2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Signal2Menu_2Selected, true);
            app.Signal2Menu_2.Text = 'Signal 2';
            app.Signal3Menu_2 = uimenu(app.ResampleMenu);
            app.Signal3Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Signal3Menu_2Selected, true);
            app.Signal3Menu_2.Text = 'Signal 3';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help...';
            app.ReadmeMenu = uimenu(app.HelpMenu);
            app.ReadmeMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadmeMenuSelected, true);
            app.ReadmeMenu.Text = 'Readme';
            app.IMaxes = uiaxes(app.UIFigure);
            title(app.IMaxes, 'Image')
            app.IMaxes.XTick = [];
            app.IMaxes.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Signal 3')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.SignalPanel = uipanel(app.UIFigure);
            app.SignalPanel.Title = 'Signal';
            app.SamplingRateLabel = uilabel(app.SignalPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate:';
            app.FsNumValSig = uilabel(app.SignalPanel);
            app.FsNumValSig.Text = '';
            app.CommonTimePointLabel = uilabel(app.SignalPanel);
            app.CommonTimePointLabel.Text = 'Common Time Point';
            app.CommonTimePointVal = uilabel(app.SignalPanel);
            app.CommonTimePointVal.Text = '';
            app.TimeLabel = uilabel(app.SignalPanel);
            app.TimeLabel.Text = 'Time:';
            app.IndexLabel = uilabel(app.SignalPanel);
            app.IndexLabel.Text = 'Index:';
            app.TimeVal = uilabel(app.SignalPanel);
            app.TimeVal.Text = '';
            app.SelectCommonTimepointButton = uibutton(app.SignalPanel, 'push');
            app.SelectCommonTimepointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCommonTimepointButtonPushed, true);
            app.SelectCommonTimepointButton.Text = 'Select Common Timepoint';
            app.FrameControlPanel = uipanel(app.UIFigure);
            app.FrameControlPanel.Title = 'Frame Control';
            app.BackButton = uibutton(app.FrameControlPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.FrameControlPanel, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.LockButton = uibutton(app.FrameControlPanel, 'push');
            app.LockButton.ButtonPushedFcn = createCallbackFcn(app, @LockButtonPushed, true);
            app.LockButton.Text = 'Lock';
            app.TenDownBtn = uibutton(app.FrameControlPanel, 'push');
            app.TenDownBtn.ButtonPushedFcn = createCallbackFcn(app, @TenDownBtnPushed, true);
            app.TenDownBtn.Text = '-10';
            app.TenUpBtn = uibutton(app.FrameControlPanel, 'push');
            app.TenUpBtn.ButtonPushedFcn = createCallbackFcn(app, @TenUpBtnButtonPushed, true);
            app.TenUpBtn.Text = '+10';
            app.ThirtyDownBtn = uibutton(app.FrameControlPanel, 'push');
            app.ThirtyDownBtn.ButtonPushedFcn = createCallbackFcn(app, @ThirtyDownBtnButtonPushed, true);
            app.ThirtyDownBtn.Text = '-30';
            app.ThirtyUpBtn = uibutton(app.FrameControlPanel, 'push');
            app.ThirtyUpBtn.ButtonPushedFcn = createCallbackFcn(app, @ThirtyUpBtnButtonPushed, true);
            app.ThirtyUpBtn.Text = '+30';
            app.PlayButton = uibutton(app.FrameControlPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.VideoFrameLabel = uilabel(app.UIFigure);
            app.VideoFrameLabel.Text = 'Video Frame';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [1 100];
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Value = 1;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Signal2Panel = uipanel(app.UIFigure);
            app.Signal2Panel.Title = 'Signal 2';
            app.SamplingRateLabel_2 = uilabel(app.Signal2Panel);
            app.SamplingRateLabel_2.Text = 'Sampling Rate:';
            app.FsNumValSig_2 = uilabel(app.Signal2Panel);
            app.FsNumValSig_2.Text = '';
            app.CommonTimePointLabel_2 = uilabel(app.Signal2Panel);
            app.CommonTimePointLabel_2.Text = 'Common Time Point';
            app.CommonTimePointVal_2 = uilabel(app.Signal2Panel);
            app.CommonTimePointVal_2.Text = '';
            app.TimeLabel_2 = uilabel(app.Signal2Panel);
            app.TimeLabel_2.Text = 'Time:';
            app.IndexLabel_2 = uilabel(app.Signal2Panel);
            app.IndexLabel_2.Text = 'Index:';
            app.TimeVal_2 = uilabel(app.Signal2Panel);
            app.TimeVal_2.Text = '';
            app.SelectCommonTimepointButton_2 = uibutton(app.Signal2Panel, 'push');
            app.SelectCommonTimepointButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectCommonTimepointButton_2Pushed, true);
            app.SelectCommonTimepointButton_2.Text = 'Select Common Timepoint';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MetaTab = uitab(app.TabGroup);
            app.MetaTab.Title = 'Meta';
            app.FrameLabel = uilabel(app.MetaTab);
            app.FrameLabel.Text = 'Frame: ';
            app.FrameNum = uilabel(app.MetaTab);
            app.FrameNum.Text = '';
            app.MetadataLabel = uilabel(app.MetaTab);
            app.MetadataLabel.Text = 'Metadata:';
            app.FsLabel = uilabel(app.MetaTab);
            app.FsLabel.Text = 'Fs:';
            app.FsNumValue = uilabel(app.MetaTab);
            app.FsNumValue.Text = '';
            app.TotalFramesLabel = uilabel(app.MetaTab);
            app.TotalFramesLabel.Text = 'Total Frames:';
            app.TotalFramesValue = uilabel(app.MetaTab);
            app.TotalFramesValue.Text = '';
            app.DurationLabel = uilabel(app.MetaTab);
            app.DurationLabel.Text = 'Duration:';
            app.DurationValue = uilabel(app.MetaTab);
            app.DurationValue.Text = '';
            app.Curr_timeLabel = uilabel(app.MetaTab);
            app.Curr_timeLabel.Text = 'Curr_time:';
            app.CurrTimeVal = uilabel(app.MetaTab);
            app.CurrTimeVal.Text = '';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.Panel_2 = uipanel(app.ImageTab);
            app.ResolutionLabel = uilabel(app.Panel_2);
            app.ResolutionLabel.Text = 'Resolution:';
            app.MeanRLabel = uilabel(app.Panel_2);
            app.MeanRLabel.Text = 'Mean R:';
            app.redval = uilabel(app.Panel_2);
            app.redval.Text = '';
            app.MeanGLabel = uilabel(app.Panel_2);
            app.MeanGLabel.Text = 'Mean G:';
            app.greenval = uilabel(app.Panel_2);
            app.greenval.Text = '';
            app.MeanBLabel = uilabel(app.Panel_2);
            app.MeanBLabel.Text = 'Mean B:';
            app.blueval = uilabel(app.Panel_2);
            app.blueval.Text = '';
            app.CalculateRGBforCurrentFrameButton = uibutton(app.Panel_2, 'push');
            app.CalculateRGBforCurrentFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRGBforCurrentFrameButtonPushed, true);
            app.CalculateRGBforCurrentFrameButton.Text = 'Calculate RGB for Current Frame';
            app.ResVal = uilabel(app.Panel_2);
            app.ResVal.Text = '0';
            app.Signal3Panel = uipanel(app.UIFigure);
            app.Signal3Panel.Title = 'Signal 3';
            app.SamplingRateLabel_3 = uilabel(app.Signal3Panel);
            app.SamplingRateLabel_3.Text = 'Sampling Rate:';
            app.FsNumValSig_3 = uilabel(app.Signal3Panel);
            app.FsNumValSig_3.Text = '';
            app.CommonTimePointLabel_3 = uilabel(app.Signal3Panel);
            app.CommonTimePointLabel_3.Text = 'Common Time Point';
            app.CommonTimePointVal_3 = uilabel(app.Signal3Panel);
            app.CommonTimePointVal_3.Text = '';
            app.TimeLabel_3 = uilabel(app.Signal3Panel);
            app.TimeLabel_3.Text = 'Time:';
            app.IndexLabel_3 = uilabel(app.Signal3Panel);
            app.IndexLabel_3.Text = 'Index:';
            app.TimeVal_3 = uilabel(app.Signal3Panel);
            app.TimeVal_3.Text = '';
            app.SelectCommonTimepointButton_3 = uibutton(app.Signal3Panel, 'push');
            app.SelectCommonTimepointButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectCommonTimepointButton_3Pushed, true);
            app.SelectCommonTimepointButton_3.Text = 'Select Common Timepoint';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.RenameSignalMenu = uimenu(app.ContextMenu2);
            app.RenameSignalMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameSignalMenuSelected, true);
            app.RenameSignalMenu.Text = 'Rename Signal';
            app.RenameDepVarMenu_2 = uimenu(app.ContextMenu2);
            app.RenameDepVarMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RenameDepVarMenu_2Selected, true);
            app.RenameDepVarMenu_2.Text = 'Rename Dep Var';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.RenameSignalMenu_2 = uimenu(app.ContextMenu3);
            app.RenameSignalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RenameSignalMenu_2Selected, true);
            app.RenameSignalMenu_2.Text = 'Rename Signal';
            app.RenameDepVarMenu = uimenu(app.ContextMenu3);
            app.RenameDepVarMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameDepVarMenuSelected, true);
            app.RenameDepVarMenu.Text = 'Rename Dep Var';
            % Assign app.ContextMenu3
            app.UIAxes_2.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.RenameSignalMenu_3 = uimenu(app.ContextMenu4);
            app.RenameSignalMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RenameSignalMenu_3Selected, true);
            app.RenameSignalMenu_3.Text = 'Rename Signal';
            app.RenameDepVarMenu_3 = uimenu(app.ContextMenu4);
            app.RenameDepVarMenu_3.MenuSelectedFcn = createCallbackFcn(app, @RenameDepVarMenu_3Selected, true);
            app.RenameDepVarMenu_3.Text = 'Rename Dep Var';
            % Assign app.ContextMenu4
            app.UIAxes_3.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiMatch(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end